classdef HSI_Comparer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        LoadandcorrectimageButton  matlab.ui.control.Button
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.EditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.EditField
        UIAxes_spec                matlab.ui.control.UIAxes
        filenameDropDownLabel      matlab.ui.control.Label
        filenameDropDown           matlab.ui.control.DropDown
        UpdatefileslistButton      matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HSI % Hyperspectral image
        lambda % wavelength
        nx % number of pixels in x direction
        ny % number of pixels in y direction
        nb % number of bands
        HSI_c % corrected image
        nx_c
        ny_c % Description
        screen_c % Description  
        n_offset % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %todo; save pwd
            % cd to this file
            %             run('../setup_tools.m')
            % cd to previos path
            
            info = dir('*_corrected.raw');
            [n, ~] = size(info) ;
            names = {};
            for i = 1:n
                names{end+1} = info(i).name;
            end
            app.filenameDropDown.Items = names;
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: LoadandcorrectimageButton
        function LoadandcorrectimageButtonPushed(app, event)
            file_path = app.filenameDropDown.Value;
            
            [filepath, name, ext] = fileparts(file_path); %ditch the extions
            filename = [filepath,name];
            [app.HSI, app.lambda, app.nx, app.ny, app.nb] = HSI_reader(filename);
%             display([app.nx, app.ny])
%             display(size(app.HSI))
            HSI_spectral_average = ones(app.nx,app.ny);
            for i = 1:app.nx
                for j = 1:app.ny
                    HSI_spectral_average(i,j) = mean(app.HSI(i,j,:));
                end
            end
            
            screen = HSI_spectral_average;
            %             screen = app.HSI(:,:,50); % if you want a specific wavelength
            %             imagesc(app.UIAxes,screen)
            imagesc(app.UIAxes, screen);
            colorbar(app.UIAxes)
            axis(app.UIAxes,'equal')
            app.UIFigure.Pointer = 'arrow';
            % 'crosshair', 'watch', not yet available; ref: https://se.mathworks.com/matlabcentral/answers/496366-how-do-i-change-the-pointer-property-on-a-ui-figure
            app.UIAxes.Toolbar
            
            [app.HSI_c, app.n_offset] = HSI_read_and_correct(filename);
            app.n_offset = app.n_offset-1;
            [app.nx_c, app.ny_c, ~] = size(app.HSI_c);
            
            HSI_spectral_average_c = ones(app.nx_c,app.ny_c);
            for i = 1:app.nx_c
                for j = 1:app.ny_c
                    HSI_spectral_average_c(i,j) = mean(app.HSI_c (i,j,:));
                end
            end
            app.screen_c = HSI_spectral_average_c;
            imagesc(app.UIAxes_2, app.screen_c);
            axis(app.UIAxes_2,'equal')
            %             colorbar(app.UIAxes_2)
            app.UIFigure.Pointer = 'arrow';
            % 'crosshair', 'watch', not yet available; ref: https://se.mathworks.com/matlabcentral/answers/496366-how-do-i-change-the-pointer-property-on-a-ui-figure
            app.UIAxes_2.Toolbar
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %Create if statement that determines if the user clicked on the
            %line of the top UIAxes. If they didn't, do nothing
            if ~isempty(event.Source.CurrentObject) % && isequal(event.Source.CurrentObject.Tag,'allData')
                
                %                 clf(app.UIAxes_spec,'reset')
                x = round(app.UIAxes.CurrentPoint(1));
                y = round(app.UIAxes.CurrentPoint(3));
                %                 display(app.UIAxes.CurrentPoint)
                app.xEditField.Value = num2str(x);
                app.yEditField.Value = num2str(y);
                
                spectrum = app.HSI(y,x,:);
                spectrum = spectrum(:);
                plot(app.UIAxes_spec,app.lambda, spectrum)
                hold(app.UIAxes_spec,'on')
                ylim(app.UIAxes_spec,[0 1])
                xlabel(app.UIAxes_spec,'wavelength (nm)')
                ylabel(app.UIAxes_spec,'reflectance')
                x_c = x-app.n_offset; % this could be dependend on type of HSI
                y_c = y-app.n_offset;
                
                if y_c > 0 && y_c <= app.nx_c && x_c > 0 && x_c <= app.ny_c
                    spectrum = app.HSI_c(y_c,x_c,:);
                    spectrum = spectrum(:);
                    plot(app.UIAxes_spec,app.lambda, spectrum)
                    %                     plot(app.UIAxes_spec,app.lambda(50), spectrum(50),'k.','MarkerSize',10,'HandleVisibility','off')
                    legend(app.UIAxes_spec, 'uncorrected','corrected','location','best')
                    imagesc(app.UIAxes_2, app.screen_c);
                    hold(app.UIAxes_2,'on')
                    axis(app.UIAxes_2,'equal')
                    plot(app.UIAxes_2 , x_c, y_c, 'k.', 'MarkerSize', 10)
                    xlim(app.UIAxes_2,app.UIAxes.XLim)
                    ylim(app.UIAxes_2,app.UIAxes.YLim)
                    hold(app.UIAxes_2,'off')
                else
                    display(x_c)
                    display(app.nx_c)
                    display(y_c)
                    display(app.ny_c)
                    
                end
                hold(app.UIAxes_spec,'off')


                %                 alpha = 200;
                
                %Set the XLim of the bottom UIAxes to be +/- alpha of the
                %current point
                %                 app.UIAxes_spec.XLim = [(app.a-alpha) (app.a+alpha)];
                
                %Plot the data on the bottom UIAxes
                %                 plot(app.UIAxes_spec,app.Var1)
            end
        end

        % Button pushed function: UpdatefileslistButton
        function UpdatefileslistButtonPushed(app, event)
            info = dir('*_corrected.raw');
            [n, ~] = size(info) ;
            names = {};
            for i = 1:n
                names{end+1} = info(i).name;
            end
            app.filenameDropDown.Items = names;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadandcorrectimageButton = uibutton(app.UIFigure, 'push');
            app.LoadandcorrectimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandcorrectimageButtonPushed, true);
            app.LoadandcorrectimageButton.Text = {'Load and correct '; 'image'};
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes_spec = uiaxes(app.UIFigure);
            title(app.UIAxes_spec, 'Spectrum')
            xlabel(app.UIAxes_spec, 'X')
            ylabel(app.UIAxes_spec, 'Y')
            app.filenameDropDownLabel = uilabel(app.UIFigure);
            app.filenameDropDownLabel.Text = 'filename';
            app.filenameDropDown = uidropdown(app.UIFigure);
            app.filenameDropDown.Items = {'A', 'B'};
            app.filenameDropDown.Value = 'A';
            app.UpdatefileslistButton = uibutton(app.UIFigure, 'push');
            app.UpdatefileslistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefileslistButtonPushed, true);
            app.UpdatefileslistButton.Text = 'Update files list';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Corrected')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSI_Comparer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end