classdef HSI_Palpator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        LoadimageButton         matlab.ui.control.Button
        xEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.EditField
        yEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.EditField
        UIAxes2                 matlab.ui.control.UIAxes
        HSIDropDownLabel        matlab.ui.control.Label
        HSIDropDown             matlab.ui.control.DropDown
        UpdatefileslistButton   matlab.ui.control.Button
        SearchfilesinnotyetimplementedButtonGroup  matlab.ui.container.ButtonGroup
        currentfolderButton     matlab.ui.control.RadioButton
        currentfolderandallhighersubfoldersButton  matlab.ui.control.RadioButton
        FiletypenyiButtonGroup  matlab.ui.container.ButtonGroup
        rawButton               matlab.ui.control.RadioButton
        matButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        HSI % Hyperspectral image
        lambda % wavelength
        nx % number of pixels in x direction
        ny % number of pixels in y direction
        nb % number of bands
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            info = dir('*_corrected.raw');
            [n, ~] = size(info) ;
            names = {};
            for i = 1:n
                names{end+1} = info(i).name;
            end
            app.HSIDropDown.Items = names;
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            file_path = app.HSIDropDown.Value;
            
            [filepath, name, ext] = fileparts(file_path); %ditch the extions
            filename = [filepath,name];
            [app.HSI, app.lambda, app.nx, app.ny, app.nb] = HSI_reader(filename);
            
            screen = HSI_wavelength_average(app.HSI);
            
            imagesc(app.UIAxes, screen);
            colorbar(app.UIAxes)
            axis(app.UIAxes,'equal')
            app.UIFigure.Pointer = 'arrow';
            
            axis(app.UIAxes,[1 app.nx 1 app.ny])
            app.UIFigure.Pointer = 'arrow';
            % 'crosshair', 'watch', not yet available; ref: https://se.mathworks.com/matlabcentral/answers/496366-how-do-i-change-the-pointer-property-on-a-ui-figure
            app.UIAxes.Toolbar
            %             app.xEditField.Value = 'hhoi'
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %Create if statement that determines if the user clicked on the
            %line of the top UIAxes. If they didn't, do nothing
            if ~isempty(event.Source.CurrentObject) % && isequal(event.Source.CurrentObject.Tag,'allData')
                
                x = round(app.UIAxes.CurrentPoint(1));
                y = round(app.UIAxes.CurrentPoint(3));
                app.xEditField.Value = num2str(x);
                app.yEditField.Value = num2str(y);
                spectrum = app.HSI (y,x,:);
                spectrum = spectrum(:);
                plot(app.UIAxes2,app.lambda, spectrum)
                ylim(app.UIAxes2,[0 1])
                xlabel(app.UIAxes2,'wavelength (nm)')
                ylabel(app.UIAxes2,'reflectance')
                
                
                %                 alpha = 200;
                
                %Set the XLim of the bottom UIAxes to be +/- alpha of the
                %current point
                %                 app.UIAxes2.XLim = [(app.a-alpha) (app.a+alpha)];
                
                %Plot the data on the bottom UIAxes
                %                 plot(app.UIAxes2,app.Var1)
            end
        end

        % Button pushed function: UpdatefileslistButton
        function UpdatefileslistButtonPushed(app, event)
            info = dir('*_corrected.raw');
            [n, ~] = size(info) ;
            names = {};
            for i = 1:n
                names{end+1} = info(i).name;
            end
            app.HSIDropDown.Items = names;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.HSIDropDownLabel = uilabel(app.UIFigure);
            app.HSIDropDownLabel.Text = 'HSI';
            app.HSIDropDown = uidropdown(app.UIFigure);
            app.HSIDropDown.Items = {'A', 'B'};
            app.HSIDropDown.Value = 'A';
            app.UpdatefileslistButton = uibutton(app.UIFigure, 'push');
            app.UpdatefileslistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefileslistButtonPushed, true);
            app.UpdatefileslistButton.Text = 'Update files list';
            app.SearchfilesinnotyetimplementedButtonGroup = uibuttongroup(app.UIFigure);
            app.SearchfilesinnotyetimplementedButtonGroup.Title = 'Search files in (not yet implemented)';
            app.currentfolderButton = uiradiobutton(app.SearchfilesinnotyetimplementedButtonGroup);
            app.currentfolderButton.Text = 'current folder';
            app.currentfolderButton.Value = true;
            app.currentfolderandallhighersubfoldersButton = uiradiobutton(app.SearchfilesinnotyetimplementedButtonGroup);
            app.currentfolderandallhighersubfoldersButton.Text = 'current folder and all higher subfolders';
            app.FiletypenyiButtonGroup = uibuttongroup(app.UIFigure);
            app.FiletypenyiButtonGroup.Title = 'Filetype(nyi)';
            app.rawButton = uiradiobutton(app.FiletypenyiButtonGroup);
            app.rawButton.Text = '.raw';
            app.rawButton.Value = true;
            app.matButton = uiradiobutton(app.FiletypenyiButtonGroup);
            app.matButton.Text = '.mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSI_Palpator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end